classdef GUI_FINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SimulationandSafetyMenu  matlab.ui.container.Menu
        ControlButtonsPanel      matlab.ui.container.Panel
        ResetButton              matlab.ui.control.Button
        StartSimulationButton    matlab.ui.control.Button
        ProgramRunningLamp       matlab.ui.control.Lamp
        PauseButton              matlab.ui.control.Button
        ResumeButton             matlab.ui.control.Button
        EStopButton              matlab.ui.control.Button
        LinearUR3SpeedKnob       matlab.ui.control.Knob
        LinearUR3SpeedKnobLabel  matlab.ui.control.Label
        TM12SpeedKnobLabel       matlab.ui.control.Label
        TM12SpeedKnob            matlab.ui.control.Knob
        ProgramRunningLampLabel  matlab.ui.control.Label
        RobotTeachPanel          matlab.ui.container.Panel
        Switch                   matlab.ui.control.Switch
        LinearUR3Panel           matlab.ui.container.Panel
        q1Slider_6               matlab.ui.control.Slider
        EditField                matlab.ui.control.EditField
        q2Slider_2               matlab.ui.control.Slider
        EditField_2              matlab.ui.control.EditField
        q3Slider_2               matlab.ui.control.Slider
        q3Slider_2Label          matlab.ui.control.Label
        EditField_3              matlab.ui.control.EditField
        q4Slider_2               matlab.ui.control.Slider
        EditField_4              matlab.ui.control.EditField
        q5Slider_2               matlab.ui.control.Slider
        EditField_5              matlab.ui.control.EditField
        q6Slider                 matlab.ui.control.Slider
        EditField_6              matlab.ui.control.EditField
        XSpinner                 matlab.ui.control.Spinner
        YSpinner                 matlab.ui.control.Spinner
        YSpinnerLabel            matlab.ui.control.Label
        ZSpinner                 matlab.ui.control.Spinner
        ZSpinnerLabel            matlab.ui.control.Label
        XSpinnerLabel            matlab.ui.control.Label
        q5Slider_2Label          matlab.ui.control.Label
        q6SliderLabel            matlab.ui.control.Label
        q2Slider_2Label          matlab.ui.control.Label
        q4Slider_2Label          matlab.ui.control.Label
        q1Slider_6Label          matlab.ui.control.Label
        LinearTM12Panel          matlab.ui.container.Panel
        q1Slider_7               matlab.ui.control.Slider
        EditField_7              matlab.ui.control.EditField
        q2Slider_3               matlab.ui.control.Slider
        EditField_8              matlab.ui.control.EditField
        q3Slider_3               matlab.ui.control.Slider
        EditField_9              matlab.ui.control.EditField
        q4Slider_3               matlab.ui.control.Slider
        EditField_10             matlab.ui.control.EditField
        q5Slider_3               matlab.ui.control.Slider
        EditField_11             matlab.ui.control.EditField
        q6Slider_2               matlab.ui.control.Slider
        EditField_12             matlab.ui.control.EditField
        XSpinner_2               matlab.ui.control.Spinner
        YSpinner_2               matlab.ui.control.Spinner
        YSpinner_2Label          matlab.ui.control.Label
        ZSpinner_2               matlab.ui.control.Spinner
        ZSpinner_2Label          matlab.ui.control.Label
        XSpinner_2Label          matlab.ui.control.Label
        q5Slider_3Label          matlab.ui.control.Label
        q3Slider_3Label          matlab.ui.control.Label
        q6Slider_2Label          matlab.ui.control.Label
        q2Slider_3Label          matlab.ui.control.Label
        q4Slider_3Label          matlab.ui.control.Label
        q1Slider_7Label          matlab.ui.control.Label
    end

    
   properties (Access = private)
    pauseFlag = false;
    paused = false;  % Add this property to keep track of the pause state
    estopFlag = false;
    resetFlag = false;
   end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
        app.ProgramRunningLamp.Color = 'green'; 
        
        KitBotLab2Assignment();
        
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
    app.ProgramRunningLamp.Color = 'red'; % Set lamp color to red

         while ~app.paused
             app.ProgramRunningLamp.Color = 'red'; % Set lamp color to red
             pause(0.1);
            
            if app.estopFlag == true
                app.estopFlag = false
                app.ProgramRunningLamp.Color = 'green';
                break
            end
    
            if app.resetFlag == true
                app.resetFlag = false;
                app.estopFlag = false;
                app.ProgramRunningLamp.Color = 'green';
                clf;
                clc;
    
                KitBotLab2Assignment();
                break;
            end
            
          
          end
                

        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
        % if app.paused 
        %     app.paused = false;
        %     app.ProgramRunningLamp.Color = 'green';
        % else
        %     app.paused = true;
        % 
        %     app.ProgramRunningLamp.Color = 'yellow';
        %     while app.paused
        %         pause(0.1); 
        %     end
        % end
        % 
        
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            %Toggle Estop
            app.estopFlag = ~app.estopFlag
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetFlag = ~app.resetFlag 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationandSafetyMenu = uimenu(app.UIFigure);
            app.SimulationandSafetyMenu.Text = 'Simulation and Safety';
            app.RobotTeachPanel = uipanel(app.UIFigure);
            app.RobotTeachPanel.Title = 'Robot Teach';
            app.LinearTM12Panel = uipanel(app.RobotTeachPanel);
            app.LinearTM12Panel.Title = 'Linear TM12';
            app.q1Slider_7Label = uilabel(app.LinearTM12Panel);
            app.q1Slider_7Label.Text = 'q1';
            app.q4Slider_3Label = uilabel(app.LinearTM12Panel);
            app.q4Slider_3Label.Text = 'q4';
            app.q2Slider_3Label = uilabel(app.LinearTM12Panel);
            app.q2Slider_3Label.Text = 'q2';
            app.q6Slider_2Label = uilabel(app.LinearTM12Panel);
            app.q6Slider_2Label.Text = 'q6';
            app.q3Slider_3Label = uilabel(app.LinearTM12Panel);
            app.q3Slider_3Label.Text = 'q3';
            app.q5Slider_3Label = uilabel(app.LinearTM12Panel);
            app.q5Slider_3Label.Text = 'q5';
            app.XSpinner_2Label = uilabel(app.LinearTM12Panel);
            app.XSpinner_2Label.Text = 'X';
            app.ZSpinner_2Label = uilabel(app.LinearTM12Panel);
            app.ZSpinner_2Label.Text = 'Z';
            app.ZSpinner_2 = uispinner(app.LinearTM12Panel);
            app.YSpinner_2Label = uilabel(app.LinearTM12Panel);
            app.YSpinner_2Label.Text = 'Y';
            app.YSpinner_2 = uispinner(app.LinearTM12Panel);
            app.XSpinner_2 = uispinner(app.LinearTM12Panel);
            app.EditField_12 = uieditfield(app.LinearTM12Panel, 'text');
            app.q6Slider_2 = uislider(app.LinearTM12Panel);
            app.EditField_11 = uieditfield(app.LinearTM12Panel, 'text');
            app.q5Slider_3 = uislider(app.LinearTM12Panel);
            app.EditField_10 = uieditfield(app.LinearTM12Panel, 'text');
            app.q4Slider_3 = uislider(app.LinearTM12Panel);
            app.EditField_9 = uieditfield(app.LinearTM12Panel, 'text');
            app.q3Slider_3 = uislider(app.LinearTM12Panel);
            app.EditField_8 = uieditfield(app.LinearTM12Panel, 'text');
            app.q2Slider_3 = uislider(app.LinearTM12Panel);
            app.EditField_7 = uieditfield(app.LinearTM12Panel, 'text');
            app.q1Slider_7 = uislider(app.LinearTM12Panel);
            app.LinearUR3Panel = uipanel(app.RobotTeachPanel);
            app.LinearUR3Panel.Title = 'Linear UR3';
            app.q1Slider_6Label = uilabel(app.LinearUR3Panel);
            app.q1Slider_6Label.Text = 'q1';
            app.q4Slider_2Label = uilabel(app.LinearUR3Panel);
            app.q4Slider_2Label.Text = 'q4';
            app.q2Slider_2Label = uilabel(app.LinearUR3Panel);
            app.q2Slider_2Label.Text = 'q2';
            app.q6SliderLabel = uilabel(app.LinearUR3Panel);
            app.q6SliderLabel.Text = 'q6';
            app.q5Slider_2Label = uilabel(app.LinearUR3Panel);
            app.q5Slider_2Label.Text = 'q5';
            app.XSpinnerLabel = uilabel(app.LinearUR3Panel);
            app.XSpinnerLabel.Text = 'X';
            app.ZSpinnerLabel = uilabel(app.LinearUR3Panel);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.LinearUR3Panel);
            app.YSpinnerLabel = uilabel(app.LinearUR3Panel);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.LinearUR3Panel);
            app.XSpinner = uispinner(app.LinearUR3Panel);
            app.EditField_6 = uieditfield(app.LinearUR3Panel, 'text');
            app.q6Slider = uislider(app.LinearUR3Panel);
            app.EditField_5 = uieditfield(app.LinearUR3Panel, 'text');
            app.q5Slider_2 = uislider(app.LinearUR3Panel);
            app.EditField_4 = uieditfield(app.LinearUR3Panel, 'text');
            app.q4Slider_2 = uislider(app.LinearUR3Panel);
            app.EditField_3 = uieditfield(app.LinearUR3Panel, 'text');
            app.q3Slider_2Label = uilabel(app.LinearUR3Panel);
            app.q3Slider_2Label.Text = 'q3';
            app.q3Slider_2 = uislider(app.LinearUR3Panel);
            app.EditField_2 = uieditfield(app.LinearUR3Panel, 'text');
            app.q2Slider_2 = uislider(app.LinearUR3Panel);
            app.EditField = uieditfield(app.LinearUR3Panel, 'text');
            app.q1Slider_6 = uislider(app.LinearUR3Panel);
            app.Switch = uiswitch(app.RobotTeachPanel, 'slider');
            app.Switch.Items = {'Joints', 'Cartesian'};
            app.Switch.Value = 'Joints';
            app.ControlButtonsPanel = uipanel(app.UIFigure);
            app.ControlButtonsPanel.Title = 'Control Buttons';
            app.ProgramRunningLampLabel = uilabel(app.ControlButtonsPanel);
            app.ProgramRunningLampLabel.Text = 'Program Running';
            app.TM12SpeedKnob = uiknob(app.ControlButtonsPanel, 'continuous');
            app.TM12SpeedKnobLabel = uilabel(app.ControlButtonsPanel);
            app.TM12SpeedKnobLabel.Text = 'TM12 Speed';
            app.LinearUR3SpeedKnobLabel = uilabel(app.ControlButtonsPanel);
            app.LinearUR3SpeedKnobLabel.Text = 'Linear UR3 Speed';
            app.LinearUR3SpeedKnob = uiknob(app.ControlButtonsPanel, 'continuous');
            app.EStopButton = uibutton(app.ControlButtonsPanel, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            app.ResumeButton = uibutton(app.ControlButtonsPanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.PauseButton = uibutton(app.ControlButtonsPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ProgramRunningLamp = uilamp(app.ControlButtonsPanel);
            app.StartSimulationButton = uibutton(app.ControlButtonsPanel, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.ResetButton = uibutton(app.ControlButtonsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_FINAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end