classdef GUIv3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RobotTeachPanel          matlab.ui.container.Panel
        Switch                   matlab.ui.control.Switch
        LinearUR3Panel           matlab.ui.container.Panel
        EditField_12             matlab.ui.control.EditField
        EditField_11             matlab.ui.control.EditField
        q3Slider_2               matlab.ui.control.Slider
        q3Slider_2Label          matlab.ui.control.Label
        EditField_10             matlab.ui.control.EditField
        EditField_9              matlab.ui.control.EditField
        EditField_8              matlab.ui.control.EditField
        EditField_7              matlab.ui.control.EditField
        YSpinner_2               matlab.ui.control.Spinner
        YSpinner_2Label          matlab.ui.control.Label
        ZSpinner_2               matlab.ui.control.Spinner
        ZSpinner_2Label          matlab.ui.control.Label
        XSpinner_2               matlab.ui.control.Spinner
        XSpinner_2Label          matlab.ui.control.Label
        q5Slider_2               matlab.ui.control.Slider
        q5Slider_2Label          matlab.ui.control.Label
        q6Slider_2               matlab.ui.control.Slider
        q6Slider_2Label          matlab.ui.control.Label
        q2Slider_2               matlab.ui.control.Slider
        q2Slider_2Label          matlab.ui.control.Label
        q4Slider_2               matlab.ui.control.Slider
        q4Slider_2Label          matlab.ui.control.Label
        q1Slider_2               matlab.ui.control.Slider
        q1Slider_2Label          matlab.ui.control.Label
        LinearTM12Panel          matlab.ui.container.Panel
        EditField_6              matlab.ui.control.EditField
        EditField_5              matlab.ui.control.EditField
        EditField_4              matlab.ui.control.EditField
        EditField_3              matlab.ui.control.EditField
        EditField_2              matlab.ui.control.EditField
        EditField                matlab.ui.control.EditField
        YSpinner                 matlab.ui.control.Spinner
        YSpinnerLabel            matlab.ui.control.Label
        ZSpinner                 matlab.ui.control.Spinner
        ZSpinnerLabel            matlab.ui.control.Label
        XSpinner                 matlab.ui.control.Spinner
        XSpinnerLabel            matlab.ui.control.Label
        q5Slider                 matlab.ui.control.Slider
        q5SliderLabel            matlab.ui.control.Label
        q3Slider                 matlab.ui.control.Slider
        q3SliderLabel            matlab.ui.control.Label
        q6Slider                 matlab.ui.control.Slider
        q6SliderLabel            matlab.ui.control.Label
        q2Slider                 matlab.ui.control.Slider
        q2SliderLabel            matlab.ui.control.Label
        q4Slider                 matlab.ui.control.Slider
        q4SliderLabel            matlab.ui.control.Label
        q1Slider                 matlab.ui.control.Slider
        q1SliderLabel            matlab.ui.control.Label
        ControlButtonsPanel      matlab.ui.container.Panel
        StartSimulationButton    matlab.ui.control.Button
        PauseButton              matlab.ui.control.Button
        ResumeButton             matlab.ui.control.Button
        EStopButton              matlab.ui.control.Button
        ResetButton              matlab.ui.control.Button
        LinearUR3SpeedKnob       matlab.ui.control.Knob
        LinearUR3SpeedKnobLabel  matlab.ui.control.Label
        TM12SpeedKnobLabel       matlab.ui.control.Label
        TM12SpeedKnob            matlab.ui.control.Knob
        ProgramRunningLamp       matlab.ui.control.Lamp
        ProgramRunningLampLabel  matlab.ui.control.Label
    end

    
       properties (Access = public)
    paused = false; % Assuming you want the default value to be false
    estopped = false; % New property to track E-stop state
       end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
        app.ProgramRunningLamp.Color = 'green'; % Set lamp color to gree        while ~paused
           mainTest();
        
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
        if ~app.estopped
        % Halt the robot
        app.ProgramRunningLamp.Color = 'red';
       pause(1.0); % Example logic to pause execution. Adjust as per your needs.    
        
        app.estopped = true;
    else
        % Display confirmation dialog
        selection = uiconfirm(app, 'Are you sure you want to continue?', ...
            'Resume Confirmation', ...
            'Options', {'Yes', 'No'}, ...
            'DefaultOption', 2, ...
            'CancelOption', 2);
        
        switch selection
            case 'Yes'
                % Resume the robot
                app.ProgramRunningLamp.Color = 'green';
                app.estopped = false; % Corrected the typo here
                      
            case 'No'
        end % Keep the robot halted        
        
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
    if app.paused
        % Set the paused flag to false to resume the simulation
        app.paused = false;
   
        % Set the lamp color to green to indicate the simulation is running
        app.ProgramRunningLamp.Color = 'green';
    end
        
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Light up the lamp blue
            app.ProgramRunningLamp.Color = 'blue';
            % Call the main function or method to start your program from the beginning
            app.mainFunction();
        end
        end

        % Value changed function: q1Slider_2
        function q1Slider_2ValueChanged(app, event)
    % 
    % valueq1 = app.EditField_12.Value; % Get the value from the EditField
    % app.q1Slider_2.Value = valueq1;  % Update the slider value
    % 
    % % Convert to radians and update model or animation
    % app.qUR3(1,1) = deg2rad(valueq1);
    % app.modeLUR3.model.animate(app.qUR3);
    % 
    % % Optionally, update the XYZ values if required
    % XYZ = transl(app.modeLUR3.model.fkine(app.qUR3));
    % app.XSpinner.Value = XYZ(1,1);
    % app.YSpinner.Value = XYZ(1,2);
    % app.ZSpinner.Value = XYZ(1,3);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlButtonsPanel = uipanel(app.UIFigure);
            app.ControlButtonsPanel.Title = 'Control Buttons';
            app.ProgramRunningLampLabel = uilabel(app.ControlButtonsPanel);
            app.ProgramRunningLampLabel.Text = 'Program Running';
            app.ProgramRunningLamp = uilamp(app.ControlButtonsPanel);
            app.TM12SpeedKnob = uiknob(app.ControlButtonsPanel, 'continuous');
            app.TM12SpeedKnobLabel = uilabel(app.ControlButtonsPanel);
            app.TM12SpeedKnobLabel.Text = 'TM12 Speed';
            app.LinearUR3SpeedKnobLabel = uilabel(app.ControlButtonsPanel);
            app.LinearUR3SpeedKnobLabel.Text = 'Linear UR3 Speed';
            app.LinearUR3SpeedKnob = uiknob(app.ControlButtonsPanel, 'continuous');
            app.ResetButton = uibutton(app.ControlButtonsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EStopButton = uibutton(app.ControlButtonsPanel, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            app.ResumeButton = uibutton(app.ControlButtonsPanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.PauseButton = uibutton(app.ControlButtonsPanel, 'push');
            app.PauseButton.Text = 'Pause';
            app.StartSimulationButton = uibutton(app.ControlButtonsPanel, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.RobotTeachPanel = uipanel(app.UIFigure);
            app.RobotTeachPanel.Title = 'Robot Teach';
            app.LinearTM12Panel = uipanel(app.RobotTeachPanel);
            app.LinearTM12Panel.Title = 'Linear TM12';
            app.q1SliderLabel = uilabel(app.LinearTM12Panel);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.LinearTM12Panel);
            app.q1Slider.Limits = [-180 180];
            app.q4SliderLabel = uilabel(app.LinearTM12Panel);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.LinearTM12Panel);
            app.q4Slider.Limits = [-180 180];
            app.q2SliderLabel = uilabel(app.LinearTM12Panel);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.LinearTM12Panel);
            app.q2Slider.Limits = [-180 180];
            app.q6SliderLabel = uilabel(app.LinearTM12Panel);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.LinearTM12Panel);
            app.q6Slider.Limits = [-180 180];
            app.q3SliderLabel = uilabel(app.LinearTM12Panel);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.LinearTM12Panel);
            app.q3Slider.Limits = [-180 180];
            app.q5SliderLabel = uilabel(app.LinearTM12Panel);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.LinearTM12Panel);
            app.q5Slider.Limits = [-180 180];
            app.XSpinnerLabel = uilabel(app.LinearTM12Panel);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.LinearTM12Panel);
            app.ZSpinnerLabel = uilabel(app.LinearTM12Panel);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.LinearTM12Panel);
            app.YSpinnerLabel = uilabel(app.LinearTM12Panel);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.LinearTM12Panel);
            app.EditField = uieditfield(app.LinearTM12Panel, 'text');
            app.EditField_2 = uieditfield(app.LinearTM12Panel, 'text');
            app.EditField_3 = uieditfield(app.LinearTM12Panel, 'text');
            app.EditField_4 = uieditfield(app.LinearTM12Panel, 'text');
            app.EditField_5 = uieditfield(app.LinearTM12Panel, 'text');
            app.EditField_6 = uieditfield(app.LinearTM12Panel, 'text');
            app.LinearUR3Panel = uipanel(app.RobotTeachPanel);
            app.LinearUR3Panel.Title = 'Linear UR3';
            app.q1Slider_2Label = uilabel(app.LinearUR3Panel);
            app.q1Slider_2Label.Text = 'q1';
            app.q1Slider_2 = uislider(app.LinearUR3Panel);
            app.q1Slider_2.Limits = [-180 180];
            app.q1Slider_2.ValueChangedFcn = createCallbackFcn(app, @q1Slider_2ValueChanged, true);
            app.q4Slider_2Label = uilabel(app.LinearUR3Panel);
            app.q4Slider_2Label.Text = 'q4';
            app.q4Slider_2 = uislider(app.LinearUR3Panel);
            app.q4Slider_2.Limits = [-180 180];
            app.q2Slider_2Label = uilabel(app.LinearUR3Panel);
            app.q2Slider_2Label.Text = 'q2';
            app.q2Slider_2 = uislider(app.LinearUR3Panel);
            app.q2Slider_2.Limits = [-180 180];
            app.q6Slider_2Label = uilabel(app.LinearUR3Panel);
            app.q6Slider_2Label.Text = 'q6';
            app.q6Slider_2 = uislider(app.LinearUR3Panel);
            app.q6Slider_2.Limits = [-180 180];
            app.q5Slider_2Label = uilabel(app.LinearUR3Panel);
            app.q5Slider_2Label.Text = 'q5';
            app.q5Slider_2 = uislider(app.LinearUR3Panel);
            app.q5Slider_2.Limits = [-180 180];
            app.XSpinner_2Label = uilabel(app.LinearUR3Panel);
            app.XSpinner_2Label.Text = 'X';
            app.XSpinner_2 = uispinner(app.LinearUR3Panel);
            app.ZSpinner_2Label = uilabel(app.LinearUR3Panel);
            app.ZSpinner_2Label.Text = 'Z';
            app.ZSpinner_2 = uispinner(app.LinearUR3Panel);
            app.YSpinner_2Label = uilabel(app.LinearUR3Panel);
            app.YSpinner_2Label.Text = 'Y';
            app.YSpinner_2 = uispinner(app.LinearUR3Panel);
            app.EditField_7 = uieditfield(app.LinearUR3Panel, 'text');
            app.EditField_8 = uieditfield(app.LinearUR3Panel, 'text');
            app.EditField_9 = uieditfield(app.LinearUR3Panel, 'text');
            app.EditField_10 = uieditfield(app.LinearUR3Panel, 'text');
            app.q3Slider_2Label = uilabel(app.LinearUR3Panel);
            app.q3Slider_2Label.Text = 'q3';
            app.q3Slider_2 = uislider(app.LinearUR3Panel);
            app.q3Slider_2.Limits = [-180 180];
            app.EditField_11 = uieditfield(app.LinearUR3Panel, 'text');
            app.EditField_12 = uieditfield(app.LinearUR3Panel, 'text');
            app.Switch = uiswitch(app.RobotTeachPanel, 'slider');
            app.Switch.Items = {'Joints', 'Cartesian'};
            app.Switch.Value = 'Joints';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIv3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end